classdef dm < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        PangMicrographUIFigure     matlab.ui.Figure
        TabGroup                   matlab.ui.container.TabGroup
        ParticleDetectionTab       matlab.ui.container.Tab
        ImportImageButton          matlab.ui.control.Button
        YourImageLabel             matlab.ui.control.Label
        ApplicationStatusLabel     matlab.ui.control.Label
        ApplicationstatusLamp      matlab.ui.control.Lamp
        ReadyLabel                 matlab.ui.control.Label
        AnalysisToolLabel          matlab.ui.control.Label
        RotateButton               matlab.ui.control.Button
        SpecifyButton              matlab.ui.control.Button
        ModifyButton               matlab.ui.control.Button
        FitButton                  matlab.ui.control.Button
        CalculateButton            matlab.ui.control.Button
        CorrFactSliderLabel        matlab.ui.control.Label
        CorrFactSlider             matlab.ui.control.Slider
        UITable                    matlab.ui.control.Table
        SaveDataButton             matlab.ui.control.Button
        Label                      matlab.ui.control.Label
        ReadyLabel_2               matlab.ui.control.Label
        Switch_2                   matlab.ui.control.Switch
        LocateButton               matlab.ui.control.Button
        UIAxes                     matlab.ui.control.UIAxes
        SmoothingToolkitTab        matlab.ui.container.Tab
        YourImageLabel_2           matlab.ui.control.Label
        UIAxes2                    matlab.ui.control.UIAxes
        ImportDataButton           matlab.ui.control.Button
        ImportDataButton_2         matlab.ui.control.Button
        SpectralAnalysisTab        matlab.ui.container.Tab
        DiffractionIndexingTab     matlab.ui.container.Tab
        GeometricPhaseAnalysisTab  matlab.ui.container.Tab
        DiagonalizationToolkitTab  matlab.ui.container.Tab
    end

    
    properties (Access = private) % Known issue: Scale!
        current_image    % Current image to be shown in the main plot
        
        % Startup function
        has_image_opened % Descriptor to indicate whether an image has been imported.
        ind_dm           % True: dm file, false: plain image file
        
        % Import image button
        % If we imported 'H:\user4\matlab\myfile.png'
        filename       % Image's filename ('myfile')
        pathname       % Image's pathname ('H:\user4\matlab')
        original_image % Image's original data
        % If ind_dm = false, tags, scale, units => undefined
        tags           % Tags in DM Image
        scale          % Length scale per one pixel in DM Image
        units          % Length units per one pixel in DM Image
        
        % Rotate image button
        rotated_image  % Rotated image data
        
        % Specify image button
        specified      % Descriptor to indicate whether an image has been specified.
        radius         % Atomic radius estimated from user input (integer value)
        gcorrimg       % Image containing normalized Gaussian correlation strength
        
        % Locate image buttom
        has_positions  % Descriptor to indicate whether specific sites has been detected.
        spotposx       % Detected spot point x
        spotposy       % Detected spot point y
        savedpoints    % Number of detected spot points
        
        % Fit image buttom
        gauss_corrected % Descriptor to indicate whether atomic spots has been Gaussian-fitted.
        spotcenters     % Gaussian-fitted center of detected spot point
        nofittedpoints  % Number of Gaussian-fitted spot points
        mygrid          % Cell structure storing [x,y] points on the image
        
        % Calculate image button
        angle       % Atomic deviation vector angle
        length      % Atomic deviation vector length
        xvector     % Atomic deviation x-vector (rightside)
        yvector     % Atomic deviation y-vector (upward)
        est_bsite_x % Estimated (Gaussian-fitted) position of B site atoms (x)
        est_bsite_y % Estimated (Gaussian-fitted) position of B site atoms (y)
        
        % Save data button
        est_asite_x % Estimated (Gaussian-fitted) position of A site atoms (x)
        est_asite_y % Estimated (Gaussian-fitted) position of A site atoms (y)
    end
    
    methods (Access = private)
        
        function [tags, image] = dmread(app, filename)
            % Read Digital Micrograph version 3 and 4 file and return the tag structure
            % and the image.
            %
            % This code was written using the reverse engineered information from
            % http://rsb.info.nih.gov/ij/plugins/DM3Format.gj.html
            
            fid = fopen(filename,'r','l');
            
            if fid == -1
                error('Could not open file.');
            end
            
            c = onCleanup(@()fclose(fid));
            
            version = fread(fid,1,'*int32',0,'b');
            
            %create root tags structure
            tags = struct();
            
            if version == 3
                fseek(fid,4,'cof');
            elseif version == 4
                fseek(fid,8,'cof');
            end
            byteord = fread(fid,1,'*int32',0,'b');
            
            fseek(fid,2,'cof');
            if version == 3
                ntags = fread(fid,1,'*int32',0,'b');
            elseif version == 4
                ntags = fread(fid,1,'*int64',0,'b');
            end
            
            %read in all tags
            for i=1:ntags
                [tag, fid] = readTag(app, fid, byteord, version);
                
                %some tags have no name, so let's create one
                if isfield(tag,'tname') == false
                    tag.tname = ['Unnamed' num2str(x)];
                    x = x + 1;
                end
                
                %make valid field name from tag name and remove tag name from structure
                fname = genvarname(tag.tname);
                tag = rmfield(tag,'tname');
                
                %attach tag to root tag structure
                tags.(fname) = tag;
            end
            
            %try to return the image data in convenient form
            if nargout == 2
                try
                    if isfield(tags.ImageList.Unnamed1.ImageData.Dimensions,'Unnamed2') == 0
                        %image
                        image = reshape(tags.ImageList.Unnamed1.ImageData.Data.Value, ...
                            tags.ImageList.Unnamed1.ImageData.Dimensions.Unnamed0.Value, ...
                            tags.ImageList.Unnamed1.ImageData.Dimensions.Unnamed1.Value)';
                    else
                        %3D image, e.g. EELS map
                        image = permute(reshape(tags.ImageList.Unnamed1.ImageData.Data.Value, ...
                            tags.ImageList.Unnamed1.ImageData.Dimensions.Unnamed0.Value, ...
                            tags.ImageList.Unnamed1.ImageData.Dimensions.Unnamed1.Value, ...
                            tags.ImageList.Unnamed1.ImageData.Dimensions.Unnamed2.Value),[2 1 3]);
                    end
                catch
                    warning('No image found, returning raw tags only.');
                    image = [];
                end
            end
        end


        function [tagstruct,fid] = readTag(app, fid, byteord, version)
            %this function read one tag, can be called recursively for subtags
            
            tagstruct = struct();
            
            %read tag type
            itag = dec2hex(fread(fid,1,'*int8'));
            
            %read tag name, if there is any
            ltname = fread(fid,1,'*int16',0,'b');
            if ltname > 0
                tagstruct.tname = char(fread(fid,ltname,'*char')');
            end
            
            if version == 4
                %this is the length of the tag, we don't use it at the moment, but might come in handy
                fread(fid,1,'int64',0,'b');
            end
            
            switch itag
                case '14' %tag directory
                    fseek(fid,2,'cof');
                    if version == 3
                        ntags = fread(fid,1,'int32',0,'b');
                    elseif version == 4
                        ntags = fread(fid,1,'int64',0,'b');
                    end
                    
                    x = 0;
                    for i=1:ntags
                        [out,fid] = readTag(app, fid,byteord, version);
                        
                        %some tags have no name, so let's create one
                        if isfield(out,'tname') == false
                            out.tname = ['Unnamed' num2str(x)];
                            x = x + 1;
                        end
                        fname = genvarname(out.tname);
                        out = rmfield(out,'tname');
                        
                        %attach tag to tag structure
                        tagstruct.(fname) = out;
                    end
                    
                    return;
                    
                case '15' %tag
                    fseek(fid,4,'cof'); %skip string "%%%%"
                    if version == 3
                        ninfo = fread(fid,1,'int32',0,'b');
                        info = fread(fid,ninfo,'int32',0,'b');
                    elseif version == 4
                        ninfo = fread(fid,1,'int64',0,'b');
                        info = fread(fid,ninfo,'int64',0,'b');
                    end
                    
                    switch info(1)
                        case 2 %short
                            precision{1} = '*int16';
                            nelem = 1;
                        case 3 %long
                            precision{1} = '*int32';
                            nelem = 1;
                        case 4 %ushort or unicode string
                            precision{1} = '*uint16';
                            nelem = 1;
                        case 5 %ulong
                            precision{1} = '*uint32';
                            nelem = 1;
                        case 6 %float
                            precision{1} = '*single';
                            nelem = 1;
                        case 7 %double
                            precision{1} = '*double';
                            nelem = 1;
                        case 8 %boolean
                            precision{1} = '*uint8';
                            nelem = 1;
                        case 9 %char
                            precision{1} = '*char';
                            nelem = 1;
                        case 10 %octet
                            precision{1} = '*int8'; %???
                            nelem = 1;
                        case 11 %long long
                            precision{1} = '*int64';
                            nelem = 1;
                        case 12 %unsigned long long
                            precision{1} = '*uint64';
                            nelem = 1;
                        case 15 %group
                            nentries = info(3);
                            precision = cell(nentries,1);
                            nelem = zeros(nentries,1,'int64');
                            for j = 1:nentries
                                precision{j} = get_precision(app, info(3+2*j));
                                nelem(j) = 1;
                            end
                        case 18 %string
                            error('TODO'); %don't know what to do in this case, have not encountered it
                        case 20 %array of numbers or groups
                            if info(2) == 15
                                nentries = info(4);
                                precision = cell(nentries,1);
                                nelem = zeros(nentries,1,'int64');
                                for j = 1:nentries
                                    precision{j} = get_precision(app, info(4+2*j));
                                    nelem(j) = info(end);
                                end
                            else
                                precision{1} = get_precision(app, info(2));
                                nelem = info(3);
                            end
                        otherwise
                            error('Unknown tag element');
                    end
                    nn = numel(precision);
                    for k=1:nn
                        if byteord == 0 %little endian
                            if nn == 1
                                tagstruct.Value = fread(fid,nelem(k),precision{k},0,'b');
                            else
                                tagstruct.Value{k} = fread(fid,nelem(k),precision{k},0,'b');
                            end
                        else %little endian
                            if nn == 1
                                tagstruct.Value = fread(fid,nelem(k),precision{k});
                            else
                                tagstruct.Value{k} = fread(fid,nelem(k),precision{k});
                            end
                        end
                    end
                    
                case '0' %end of file
                otherwise
                    error('Unknown Tag identifier.');
            end
        end
        
        function precision = get_precision(app, number)
            switch number
                case 2 %short
                    precision = '*int16';
                case 3 %long
                    precision = '*int32';
                case 4 %ushort or unicode string
                    precision = '*uint16';
                case 5 %ulong
                    precision = '*uint32';
                case 6 %float
                    precision = '*single';
                case 7 %double
                    precision = '*double';
                case 8 %boolean
                    precision = '*uint8';
                case 9 %char
                    precision = '*char';
                case 10 %octet
                    precision = '*int8'; % don't know if this is correct
                case 11 %long long
                    precision = '*int64';
                case 12 %unsigned long long
                    precision{1} = '*uint64';
                    
                otherwise
                    error('Unknown precision');
            end
        end
        
        function [scale, units] = read_length_info(app, tags)
            dm_x_info = tags.ImageList.Unnamed1.ImageData.Calibrations.Dimension.Unnamed0;
            dm_y_info = tags.ImageList.Unnamed1.ImageData.Calibrations.Dimension.Unnamed1;
            
            x_scale = double(dm_x_info.Scale.Value);
            y_scale = double(dm_y_info.Scale.Value);
            
            x_units = char(dm_x_info.Units.Value');
            y_units = char(dm_y_info.Units.Value');
            
            is_x_calibrated = true;
            is_y_calibrated = true;
            if (numel(x_units)<1)
                is_x_calibrated = false;
                x_units = 'Unknown';
            end
            if (numel(y_units)<1)
                is_y_calibrated = false;
                y_units = 'Unknown';
            end
            
            if (~is_x_calibrated || ~is_y_calibrated)
                my_message = {['DM x-direction length per pixel = ' num2str(x_scale)], ...
                    ['DM x-direction unit   per pixel = ' x_units], ...
                    ['DM y-direction length per pixel = ' num2str(y_scale)], ...
                    ['DM y-direction unit   per pixel = ' y_units]};
                title = 'Pixel Info';
                lines = 1;
                default = {num2str(x_scale), x_units, num2str(y_scale), y_units};
                answer = inputdlg(my_message, title, lines, default);
                
                if(numel(answer)<1)
                    scale = [x_scale,y_scale];
                    units = {x_units,y_units};
                    return;
                else
                    %assignin('caller', 'x_scale', answer{1});
                    %assignin('caller', 'x_units', answer{2});
                    %assignin('caller', 'y_scale', answer{3});
                    %assignin('caller', 'y_units', answer{4});
                    x_scale= str2double(answer{1});
                    x_units= answer{2};
                    y_scale= str2double(answer{3});
                    y_units= answer{4};
                end
            end
            scale = [x_scale,y_scale];
            units = {x_units, y_units};
            %units(1) = x_units;
            %units(2) = y_units;%[x_units,y_units];
        end
        
        function gaussian_matrix = gauss2d(app, variables, row_col_grid)
            % [R,C] = ndgrid(size(data));
            % row_col_grid{1} = R;
            % row_col_grid{2} = C;
            row_center  = variables(1);
            col_center  = variables(2);
            row_sigma   = variables(3);
            col_sigma   = variables(4);
            intensity   = variables(5);
            
            row_grid = row_col_grid{1};
            col_grid = row_col_grid{2};
            
            exponent        = ((col_grid-col_center).^2)./(2*col_sigma) + ...
                              ((row_grid-row_center).^2)./(2*row_sigma);
            gaussian_matrix = intensity .* exp(-exponent);
        end
        
        function gaussian_matrix = norm_sym_gauss2d(app, variables, row_col_grid)
            % gauss2d assuming that row_sigma = col_sigma & intensity = 1
            % [R,C] = ndgrid(size(data));
            % row_col_grid{1} = R;
            % row_col_grid{2} = C;
            row_center  = variables(1);
            col_center  = variables(2);
            sigma       = variables(3);
            
            row_grid = row_col_grid{1};
            col_grid = row_col_grid{2};
            
            exponent        = ((col_grid-col_center).^2)./(2*sigma) + ...
                              ((row_grid-row_center).^2)./(2*sigma);
            gaussian_matrix = exp(-exponent);
        end
        
        function plot_array_point(app,rind,cind,markersize,color,flag)
            angles = 0:2*pi/100:2*pi;
            spotsy = markersize * sin(angles) + rind; %markersize * sin(angles) + rsize - rind;
            spotsx = markersize * cos(angles) + cind; %markersize * cos(angles) + csize - cind;
            plot(spotsx,spotsy,['-' color],'LineWidth',1)
            if (flag)
                plot(cind,rind,'rx');
            end
        end
        
        function [centerx,centery] = MeasureImage(app,cogimg,iterations)
            
            gaussian = @norm_sym_gauss2d;
            function gaussian_matrix = norm_sym_gauss2d(variables, row_col_grid)
                % gauss2d assuming that row_sigma = col_sigma & intensity = 1
                % [R,C] = ndgrid(size(data));
                % row_col_grid{1} = R;
                % row_col_grid{2} = C;
                row_center  = variables(1);
                col_center  = variables(2);
                sigma       = variables(3);
                %intensity   = variables(4);
                
                row_grid = row_col_grid{1};
                col_grid = row_col_grid{2};
                
                exponent = ((col_grid-col_center).^2)./(2*sigma^2) + ...
                           ((row_grid-row_center).^2)./(2*sigma^2);
                %gaussian_matrix = intensity*exp(-exponent);
                gaussian_matrix = exp(-exponent);
            end
            
            cogmin = min(min(cogimg));
            cogmax = max(max(cogimg));
            cogrange = cogmax - cogmin;
            
            % normalized; gaussian intensity for fitting = 1
            normimg = (cogimg - cogmin)/cogrange;
            
            [ysize,xsize] = size(normimg);
            centerx = xsize/2;
            centery = ysize/2;
            initialsigma = xsize/4;
            initialintensity = 0.8;
            
            [R,C] = ndgrid(1:ysize,1:xsize);
            row_col_grid{1} = R;
            row_col_grid{2} = C;
            
            opts = optimset('Display','off');
            for i=1:iterations
                
                % (*) %%%%%%%%%%%%%%%%%%%%%%%%%
                % variables     = [ y_center, x_center , sigma ]
                % row_col_grid  = { Row , Col }
                
                %variables    = [centery, centerx, initialsigma, initialintensity];
                %lb = [1, 1, 0.1, 0];
                %ub = [ysize, xsize, 2*min(ysize,xsize), 1];
                lb = [1, 1, 0.1];
                ub = [ysize, xsize, 2*min(ysize,xsize)];
                variables    = [centery, centerx, initialsigma];
                [x,~,residual,exitflag] = lsqcurvefit(gaussian,variables,row_col_grid,normimg,lb,ub,opts);
                %[x,chisqrd,residual,exitflag] <- lsqcurvefit
                % (*) %%%%%%%%%%%%%%%%%%%%%%%%%
                
                centerx = x(2);
                centery = x(1);
                initialsigma = x(3);
                %initialintensity = x(4);
                %debug
                %if (norm([centerx-cogx,centery-cogy]) > 10)
                %    disp(chisqrd);
                %end
                if (exitflag == 1)
                    break;
                end
            end
            if (exitflag < 1)
                disp('Warning: Fitting not convergent');
            end
        end
        
        function [centerx,centery] = MeasureImage2(app,cogimg,iterations)
            
            gausslorentz = @sym_gauss_lorentz2d;
            function gl_matrix = sym_gauss_lorentz2d(variables, row_col_grid)
                % gauss2d assuming that row_sigma = col_sigma & intensity = 1
                % [R,C] = ndgrid(size(data));
                % row_col_grid{1} = R;
                % row_col_grid{2} = C;
                row_center = variables(1);
                col_center = variables(2);
                sigma      = variables(3);
                omega      = variables(4);
                mixing     = variables(5);
                intensity  = variables(6);
                
                row_grid = row_col_grid{1};
                col_grid = row_col_grid{2};
                
                exponent = ((col_grid-col_center).^2)./(2*sigma^2) + ...
                           ((row_grid-row_center).^2)./(2*sigma^2);
                Lorentz  = 1./( 1 + ((col_grid-col_center)/omega).^2 + ((row_grid-row_center)/omega).^2 );
                gl_matrix = intensity*( mixing*exp(-exponent)+(1-mixing)*Lorentz );
            end
            
            cogmin = min(min(cogimg));
            cogmax = max(max(cogimg));
            cogrange = cogmax - cogmin;
            
            % normalized; gaussian intensity for fitting = 1
            normimg = (cogimg - cogmin)/cogrange;
            
            [ysize,xsize] = size(normimg);
            centerx = xsize/2;
            centery = ysize/2;
            initialsigma = xsize/4;
            initomega = xsize/4;
            initmixing = 0.8;
            initialintensity = 0.8;
            
            [R,C] = ndgrid(1:ysize,1:xsize);
            row_col_grid{1} = R;
            row_col_grid{2} = C;
            
            opts = optimset('Display','off');
            for i=1:iterations
                
                % (*) %%%%%%%%%%%%%%%%%%%%%%%%%
                % variables     = [ y_center, x_center , sigma ]
                % row_col_grid  = { Row , Col }
                variables    = [centery, centerx, initialsigma, initomega, initmixing, initialintensity];
                lb = [1, 1, 0.1, 0.1, 0, 0];
                ub = [ysize, xsize, 2*min(ysize,xsize), 2*min(ysize,xsize), 1, 1];
                [x,~,residual,exitflag] = lsqcurvefit(gausslorentz,variables,row_col_grid,normimg,lb,ub,opts);
                %[x,chisqrd,residual,exitflag] <- lsqcurvefit
                % (*) %%%%%%%%%%%%%%%%%%%%%%%%%
                
                centerx = x(2);
                centery = x(1);
                initialsigma = x(3);
                initomega = x(4);
                initmixing = x(5);
                initialintensity = x(6);
                %debug
                %if (norm([centerx-cogx,centery-cogy]) > 10)
                %    disp(chisqrd);
                %end
                if (exitflag == 1)
                    break;
                end
            end
            if (exitflag < 1)
                disp('Warning: Fitting not convergent');
            end
        end
        
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %selectedTab = app.TabGroup.SelectedTab;
            %if selectedTab == app.ParticleDetectionTab
            %    msgbox('1');
            %end
            app.UIAxes.XTickLabel = '';
            app.UIAxes.YTickLabel = '';
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            
            app.UITable.Data = {'Input Image','',''; 'Cropped Image','','';...
                                'Scale (x,y)','',''; 'Units (x,y)'  ,'','';};
            
            app.ind_dm           = false;
            app.has_image_opened = false;
            app.specified        = false;
            app.has_positions    = false;
            app.gauss_corrected  = false;
        end

        % Button pushed function: ImportImageButton
        function ImportImageButtonPushed(app, event)
            app.ApplicationstatusLamp.Color = 'red';
            app.ReadyLabel.Text = 'Busy';
            
            % Clear axes
            cla(app.UIAxes);
            
            [filename_l, pathname_l] = uigetfile({'*.*'}, 'File Selector');
            if app.filename == 0
                app.ApplicationstatusLamp.Color = 'green';
                app.ReadyLabel.Text = 'Ready';
                return;
            end
            
            try
                myfile = sprintf('%s%s',pathname_l,filename_l);
                [app.pathname,app.filename,ext] = fileparts(myfile);
                app.ind_dm = numel(strfind(lower(ext),'dm'));
                
                if app.ind_dm
                    [app.tags, myimage] = dmread(app, myfile);
                    app.original_image  = mat2gray(myimage);
                    
                    [app.scale, app.units] = read_length_info(app, app.tags);
                    app.ind_dm = true;
                else
                    myimage = imread(myfile);
                    app.original_image = mat2gray(myimage);
                    app.scale = [1 1];
                    app.units{1} = 'pixel';
                    app.units{2} = 'pixel';  
                end
            catch
                uiwait(msgbox('Failed to Open the File', 'Error', 'error'));
                app.ApplicationstatusLamp.Color = 'green';
                app.ReadyLabel.Text = 'Ready';
                return;
            end
            
            [row,col] = size(app.original_image);
            if (row == 1 || col == 1)
                uiwait(msgbox('Input Image is One-dimensional.', 'Error', 'error'));
                app.ApplicationstatusLamp.Color = 'green';
                app.ReadyLabel.Text = 'Ready';
                return;
            end
            
            % Display image and stretch to fill axes
            I = imshow(app.original_image,'Parent',app.UIAxes, ...
                'XData', [1 app.UIAxes.Position(3)], ...
                'YData', [1 app.UIAxes.Position(4)]);
            % Set limits of axes
            app.UIAxes.XLim = [0 I.XData(2)];
            app.UIAxes.YLim = [0 I.YData(2)];
            
            app.has_image_opened = true;
            app.current_image = app.original_image;
            
            app.UITable.Data = {'Input Image',[num2str(row) ' by ' num2str(col)],'';...
                                'Cropped Image','','';...
                                'Scale (x,y)',[num2str(app.scale(1)) ' and ' num2str(app.scale(1))],'';...
                                'Units (x,y)',[app.units{1} ' and ' app.units{2}],''};
            
            app.ApplicationstatusLamp.Color = 'green';
            app.ReadyLabel.Text = 'Ready';
        end

        % Button pushed function: RotateButton
        function RotateButtonPushed(app, event)
            app.ApplicationstatusLamp.Color = 'red';
            app.ReadyLabel.Text = 'Busy';
            
            try
                if (~app.has_image_opened)
                    uiwait(msgbox({'No Image Exists.' 'Please open your image file.'}, 'Error', 'error'));
                    app.ApplicationstatusLamp.Color = 'green';
                    app.ReadyLabel.Text = 'Ready';
                    return;
                end
            catch
                uiwait(msgbox({'No Image Exists.' 'Please open your image file.'}, 'Error', 'error'));
                app.ApplicationstatusLamp.Color = 'green';
                app.ReadyLabel.Text = 'Ready';
                return;
            end
            
            message = sprintf(['Select two points on your screen using the mouse.\n' ...
                ,'You image will be rotated by certain degree as\n'...
                ,'a line through the points becomes horizontal.']);
            uiwait(msgbox(message));
            
            h = figure(200);
            imshow(app.current_image);
            hold on;
            try
                [x,y] = ginput(1);
                arrow_start_x = x;
                arrow_start_y = y;
                plot(x,y,'rx');
                
                [x,y] = ginput(1);
                arrow_end_x   = x;
                arrow_end_y   = y;
                hold off;
                close(h);
            catch
                uiwait(msgbox({'Wrong Point Selection' 'Please re-open your image file.'}, 'Error', 'error'));
                app.ApplicationstatusLamp.Color = 'green';
                app.ReadyLabel.Text = 'Ready';
                return;
            end
            
            deltax = abs(arrow_end_x - arrow_start_x);
            deltay = abs(arrow_end_y - arrow_start_y);
            angle = atan(deltay/deltax); % angle in radian
            startangle = angle*180/pi-2;
            
            % rotate the image 90 degs clockwise
            % why should I do this?
            % columnimage = rot90(app.origin_image, 3);
            columnimage = app.current_image;
            angularstepsize = 0.05;
            
            % Estimate rotation angle needed for the image using user input.
            h = waitbar(0,'Finding optimum rotation angle...');
            angularintensity = [];
            for i=0:angularstepsize:4    % rotation angle +- 2 degree
                sweepangle = startangle + i;
                rotateimg = imrotate(columnimage, 360-sweepangle, 'bicubic');
                
                [rotxsize, rotysize] = size(rotateimg);
                
                centralpart = rotateimg(ceil(0.1*rotxsize):floor(0.9*rotysize), ...
                    ceil(0.1*rotysize):floor(0.9*rotxsize));
                
                intensityprofile = sum(centralpart, 1);
                
                [~, centy] = size(centralpart);
                
                intensityprofile = intensityprofile / centy;
                maxval = max(intensityprofile);
                
                angularintensity = [angularintensity, maxval];
                waitbar(i/4);
            end
            close(h);
            
            [~, maxpixelpos] = max(angularintensity);
            finalangle = startangle + (maxpixelpos*angularstepsize);
            temp = imrotate(app.current_image,360-finalangle,'bicubic');
            finalangle = finalangle/(180/pi); % Optimized angle in radian
            
            % Start function rotateAndCrop
            [origheight,origwidth] = size(app.current_image);
            
            if (origwidth >= origheight)
                longside = origwidth;
                shortside = origheight;
                widthlonger = 1;
            else
                longside = origheight;
                shortside = origwidth;
                widthlonger = 0;
            end
            
            sina = abs(sin(finalangle));
            cosa = abs(cos(finalangle));
            
            if (shortside <= (2*sina*cosa*longside))
                x = 0.5*shortside;
                if(widthlonger==1)
                    rotatedwidth = x/sina;
                    rotatedheight = x/cosa;
                else
                    rotatedwidth = x/cosa;
                    rotatedheight = x/sina;
                end
            else
                cos2a = cosa*cosa - sina*sina;
                rotatedwidth = (origwidth*cosa - origheight*sina)/cos2a;
                rotatedheight = (origheight*cosa - origwidth*sina)/cos2a;
            end
            
            [rotysize,rotxsize] = size(temp);
            rotcentrex = rotxsize/2;
            rotcentrey = rotysize/2;
            top = round(rotcentrey-(rotatedheight/2));
            left = round(rotcentrex-(rotatedwidth/2));
            right = round(rotcentrex+(rotatedwidth/2));
            bottom = round(rotcentrey+(rotatedheight/2));
            
            if(top<1)
                top = 1;
            end
            if(left<1)
                left = 1;
            end
            if(right>rotxsize)
                right = rotxsize;
            end
            if(bottom>rotysize)
                bottom=rotysize;
            end
            
            app.rotated_image = temp(top:bottom,left:right);
            cla(app.UIAxes);
            
            
            % Display image and stretch to fill axes
            I = imshow(app.rotated_image,'Parent',app.UIAxes, ...
                'XData', [1 app.UIAxes.Position(3)], ...
                'YData', [1 app.UIAxes.Position(4)]);
            % Set limits of axes
            app.UIAxes.XLim = [0 I.XData(2)];
            app.UIAxes.YLim = [0 I.YData(2)];
            
            app.current_image = app.rotated_image;
            
            uiwait(msgbox({'Your image has been rotated by' [num2str(finalangle*180/pi) ' degrees.']}, 'Result'));
            app.ApplicationstatusLamp.Color = 'green';
            app.ReadyLabel.Text = 'Ready';
        end

        % Button pushed function: SpecifyButton
        function SpecifyButtonPushed(app, event)
            app.ApplicationstatusLamp.Color = 'red';
            app.ReadyLabel.Text = 'Busy';
            try
                if (~app.has_image_opened)
                    uiwait(msgbox({'No Image Exists.' 'Please import an image first.'}, 'Error', 'error'));
                    app.ApplicationstatusLamp.Color = 'green';
                    app.ReadyLabel.Text = 'Ready';
                    return;
                end
            catch
                uiwait(msgbox({'No Image Exists.' 'Please import an image first.'}, 'Error', 'error'));
                app.ApplicationstatusLamp.Color = 'green';
                app.ReadyLabel.Text = 'Ready';
                return;
            end
            
            % estimated radius of spots
            % input from panel, but I think input is not necessary.
            % searcharea = 10;
            uiwait(msgbox({'Please select two axial points of an atom' 'to estimate the size of atomic spots.'}));
            h = figure(164);
            imshow(app.current_image);
            hold on;
            try
                [x,y] = ginput(1);
                one_x = x;
                one_y = y;
                plot(x,y,'rx');
                
                [x,y] = ginput(1);
                two_x = x;
                two_y = y;
                hold off;
                close(h);
                
                atomsize = norm([one_x - two_x, one_y - two_y]);
                app.radius = round(atomsize/2);
            catch
                uiwait(msgbox({'Wrong Point Selection' 'Please re-execute the specify button.'}, 'Error', 'error'));
                app.ApplicationstatusLamp.Color = 'green';
                app.ReadyLabel.Text = 'Ready';
                return;
            end
            
            uiwait(msgbox({'Please select two diagonal atomic sites' 'to set the area of interest.'}));
            h = figure(218);
            imshow(app.current_image);
            hold on
            
            try
                [x,y] = ginput(1);
                one_x = x;
                one_y = y;
                plot(x,y,'bo');
                
                [ysize,xsize] = size(app.current_image);
                line([1 xsize], [one_y one_y]);
                line([one_x one_x], [1 ysize]);
                
                [x,y] = ginput(1);
                two_x = x;
                two_y = y;
                plot(x,y,'bo');
                
                hold off;
                close(h);
                
            catch
                uiwait(msgbox({'Wrong Point Selection' 'Please re-execute the specify button.'}, 'Error', 'error'));
                app.ApplicationstatusLamp.Color = 'green';
                app.ReadyLabel.Text = 'Ready';
                return;
            end
            
            if (one_x > two_x)
                right_x = floor(one_x);
                left_x  = floor(two_x);
            else
                right_x = floor(two_x);
                left_x  = floor(one_x);
            end
            
            if (one_y > two_y)
                top_y    = floor(one_y);
                bottom_y = floor(two_y);
            else
                top_y    = floor(two_y);
                bottom_y = floor(one_y);
            end
            
            right_x = right_x + app.radius;
            if (right_x > xsize)
                right_x = xsize;
            end
            left_x  = left_x  - app.radius;
            if (left_x < 1)
                left_x = 1;
            end
            bottom_y = bottom_y - app.radius;
            if (bottom_y < 1)
                bottom_y = 1;
            end
            top_y    = top_y    + app.radius;
            if (top_y > ysize)
                top_y = ysize;
            end
            
            h = figure(192);
            imshow(app.current_image);
            hold on;
            
            plot([left_x,right_x,right_x,left_x,left_x],[bottom_y,bottom_y,top_y,top_y,bottom_y],'r-');
            plot(one_x,one_y,'bo');
            plot(two_x,two_y,'bo');
            hold off;
            
            uiwait(msgbox('Please check the chosen area.'));
            try
                close(h);
            catch
                
            end
            
            %please give length of pixel on the image for convenience
            %markersize per pixel
            %input from panel, but I think input is not necessary.
            %markersize = 7;
            %Half size of atom
            %app.markersize = searcharea;
            %app.radius = markersize = searcharea
            
            app.current_image = app.current_image(bottom_y:top_y,left_x:right_x);
            cla(app.UIAxes);
            
            % Display image and stretch to fill axes
            I = imshow(app.current_image,'Parent',app.UIAxes, ...
                'XData', [1 app.UIAxes.Position(3)], ...
                'YData', [1 app.UIAxes.Position(4)]);
            % Set limits of axes
            app.UIAxes.XLim = [0 I.XData(2)];
            app.UIAxes.YLim = [0 I.YData(2)];
            
            
            corr_size = 2*floor(app.radius/1.3)+1; %2*app.radius+1;        % reliable?
            %                row_center      col_center        sigma
            variables = [(corr_size-1)/2+1,(corr_size-1)/2+1,corr_size/3]; % reliable?
            [R,C] = ndgrid(1:corr_size,1:corr_size);
            temp_mygrid{1} = R;
            temp_mygrid{2} = C;
            gaussian = norm_sym_gauss2d(app, variables, temp_mygrid);
            
            [ysize,xsize] = size(app.current_image);
            
            crosscorr = zeros(ysize,xsize);
            h = waitbar(0,'Executing Gaussian correlation...');
            for i = variables(1):(ysize-variables(1)+1)
                for j = variables(2):(xsize-variables(2)+1)
                    crosscorr(i,j) = sum(sum( ...
                        gaussian .* app.current_image( (i-variables(1)+1):(i+variables(1)-1), (j-variables(2)+1):(j+variables(2)-1) ) ...
                        ));
                end
                waitbar((i-variables(1))/(ysize-2*variables(1)));
            end
            close(h);
            maxval = max(max(crosscorr));
            minval = min(min(crosscorr));
            app.gcorrimg = (crosscorr-minval)/maxval;
            
            figure(3814);
            %imshow(storageimg);
            contourf(app.gcorrimg(ysize:-1:1,:),10);
            colorbar;
            %close(h);
            uiwait(msgbox('Please check the correlation intensity.'));
            
            % Display image and stretch to fill axes
            %I = contourf(app.UIAxes,storageimg);
            %app.UIAxes.XLim = [0 xsize];
            %app.UIAxes.YLim = [0 ysize];
            
            [row,col] = size(app.original_image);
            [crw,ccl] = size(app.current_image);
            app.UITable.Data = {'Input Image',[num2str(row) ' by ' num2str(col)],'';...
                                'Cropped Image',[num2str(crw) ' by ' num2str(ccl)],'';...
                                'Scale (x,y)',[num2str(app.scale(1)) ' and ' num2str(app.scale(2))],'';...
                                'Units (x,y)',[app.units{1} ' and ' app.units{2}],''};
            
            app.specified = true;
            
            app.ApplicationstatusLamp.Color = 'green';
            app.ReadyLabel.Text = 'Ready';
        end

        % Button pushed function: LocateButton
        function LocateButtonPushed(app, event)
            app.ApplicationstatusLamp.Color = 'red';
            app.ReadyLabel.Text = 'Busy';
            
            try
                if (~app.specified)
                    uiwait(msgbox({'No Specified Image Exists.' 'Please specify your image file.'}, 'Error', 'error'));
                    app.ApplicationstatusLamp.Color = 'green';
                    app.ReadyLabel.Text = 'Ready';
                    return;
                end
            catch
                uiwait(msgbox({'No Specified Image Exists.' 'Please specify your image file.'}, 'Error', 'error'));
                app.ApplicationstatusLamp.Color = 'green';
                app.ReadyLabel.Text = 'Ready';
                return;
            end
            

            threshold  = str2double(app.Label.Text);
            storageimg = app.gcorrimg;

            [ysize,xsize] = size(storageimg);
            app.spotposx = [];
            app.spotposy = [];
            app.savedpoints = 0;
            
            
            %for i=1:spotstofind
            while(true)
                
                [maxval, current_max_y] = max(storageimg);
                [current_max_val, current_max_x] = max(maxval);
                current_max_y = current_max_y(current_max_x);
                
                % Above three lines are equivalent to
                % [maxval, maxind] = max(storageimg);
                % [current_max_y, current_max_x] = ind2sub(size(storageimg),maxind);
                
                % check that the maximum is still above the threshold value
                if (current_max_val < threshold)
                    break;
                end
                
                top = current_max_y - app.radius;
                if (top<1)
                    top = 1;
                end
                
                left = current_max_x - app.radius;
                if (left<1)
                    left = 1;
                end
                
                bottom = current_max_y + app.radius;
                if (bottom > ysize)
                    bottom = ysize;
                end
                
                right = current_max_x + app.radius;
                if (right > xsize)
                    right = xsize;
                end
                
                %offsetx = (right-left)/2;
                %offsety = (bottom-top)/2;
                
                % what is the "front"?
                [maxval,finalmaxy] = max(storageimg(top:bottom,left:right));
                [~, finalmaxx] = max(maxval);
                finalmaxy = finalmaxy(finalmaxx);
                
                finalmaxx = finalmaxx + left - 1;
                finalmaxy = finalmaxy + top - 1;
                
                storageimg(top:bottom,left:right) = 0;
                
                app.spotposx = [app.spotposx, finalmaxx];
                app.spotposy = [app.spotposy, finalmaxy];
                app.savedpoints = app.savedpoints + 1;
                
            end
            
            h = figure(1904);
            imshow(app.current_image);
            hold on
            temp = [];
            
            %find circles that deviate from the image
            for i=1:app.savedpoints
                top = app.spotposy(i);
                left = app.spotposx(i);
                bottom = app.spotposy(i);
                right = app.spotposx(i);
                if (top>0 && left>0 && bottom<ysize && right<xsize)
                    temp = [temp, i];
                end
            end
            
            app.savedpoints = numel(temp);
            app.spotposy    = app.spotposy(temp);
            app.spotposx    = app.spotposx(temp);
            
            
            %find circles that are duplicated or too close to each other
            temp=[];
            for i=app.savedpoints:-1:2
                % should not be 1:app.savedpoints-1
                % since it will erase points with high intensities at first
                distance = [];
                for j=(i-1):-1:1 %(i+1):app.savedpoints
                    
                    deltax = (app.spotposx(i) - app.spotposx(j))^2;
                    deltay = (app.spotposy(i) - app.spotposy(j))^2;
                    distance = [distance, sqrt(deltax+deltay)];
                end
                min_distance = min(distance);
                if (min_distance>app.radius*2) % vs. markersize
                    temp = [temp, i];
                end
            end
            %temp = [temp,app.savedpoints];
            temp = [temp,1];
            
            app.savedpoints = numel(temp);
            app.spotposy    = app.spotposy(temp);
            app.spotposx    = app.spotposx(temp);
            
            for i=1:app.savedpoints
                plot_array_point(app, app.spotposy(i), app.spotposx(i), app.radius, 'm', true);
            end
            hold off
            
            uiwait(msgbox({['Successfully checked ' num2str(app.savedpoints) ' atomic positions.']}));
            
            uiwait(msgbox({'Please check that the atomic positions are reasonably estimated.' ...
                'If inappropriate, adjust your threshold value or,' ...
                'press the modify button.'}));
            
            try
                close(h);
            catch
                
            end
            
            app.has_positions = true;
            app.ApplicationstatusLamp.Color = 'green';
            app.ReadyLabel.Text = 'Ready';
        end

        % Button pushed function: ModifyButton
        function ModifyButtonPushed(app, event)
            app.ApplicationstatusLamp.Color = 'red';
            app.ReadyLabel.Text = 'Busy';
            
            try
                if (~app.has_positions)
                    uiwait(msgbox({'No Atomic Position Checked.' 'Please press the locate button first.'}, 'Error', 'error'));
                    app.ApplicationstatusLamp.Color = 'green';
                    app.ReadyLabel.Text = 'Ready';
                    return;
                end
            catch
                uiwait(msgbox({'No Atomic Position Checked.' 'Please press the locate button first.'}, 'Error', 'error'));
                app.ApplicationstatusLamp.Color = 'green';
                app.ReadyLabel.Text = 'Ready';
                return;
            end
            
            
            h = figure(1244);
            imshow(app.current_image);
            hold on
            for i=1:app.savedpoints
                plot_array_point(app, app.spotposy(i), app.spotposx(i), app.radius,'m',true);
            end
            
            choice = questdlg('Do you want to modify any point?', ... % Question
                'Modification', ...                     % Title
                'Yes',     'No',    'No');
            % choice1  choice2  default
            % Handle response
            switch choice
                case 'Yes'
                    uiwait(msgbox({'Please select all atomic sites' 'that you want to modify.'}));
                    
                    mod_points = [];
                    
                    while(true)
                        try
                            [x,y] = ginput(1);
                            plot(x,y,'bo');
                            mod_points = [mod_points ; round([x,y])];
                        catch
                            try
                                %hold off;
                                close(h);
                            catch
                            end
                            
                            break;
                        end
                    end
                    
                    if (numel(mod_points) > 0)
                        [num_mod,~] = size(mod_points);
                        
                        for i=1:num_mod
                            distance = [];
                            for j=1:app.savedpoints
                                deltax = (app.spotposx(j) - mod_points(i,1))^2;
                                deltay = (app.spotposy(j) - mod_points(i,2))^2;
                                distance = [distance, sqrt(deltax+deltay)];
                            end
                            
                            [min_distance, min_ind] = min(distance);
                            if (min_distance>app.radius)
                                app.savedpoints = app.savedpoints + 1;
                                app.spotposy    = [app.spotposy, mod_points(i,2)];
                                app.spotposx    = [app.spotposx, mod_points(i,1)];
                            else
                                app.savedpoints = app.savedpoints - 1;
                                app.spotposy(min_ind) = [];
                                app.spotposx(min_ind) = [];
                            end
                        end
                    end
                    
                    h = figure(134);
                    imshow(app.current_image);
                    hold on
                    
                    for i=1:app.savedpoints
                        plot_array_point(app, app.spotposy(i), app.spotposx(i), app.radius,'m',true);
                    end
                    hold off
                    
                    uiwait(msgbox({'Please check that the atomic positions are reasonably estimated.' ...
                        'If inappropriate, adjust your threshold value or,' ...
                        'press the modify button.'}));
                    try
                        close(h);
                    catch
                    end
                    
                case 'No'
                    try
                        close(h);
                    catch
                    end
            end
            
            app.ApplicationstatusLamp.Color = 'green';
            app.ReadyLabel.Text = 'Ready';
        end

        % Button pushed function: FitButton
        function FitButtonPushed(app, event)
            app.ApplicationstatusLamp.Color = 'red';
            app.ReadyLabel.Text = 'Busy';
            
            try
                if (~app.has_positions)
                    uiwait(msgbox({'No Atomic Position Checked.' 'Please press the locate button first.'}, 'Error', 'error'));
                    app.ApplicationstatusLamp.Color = 'green';
                    app.ReadyLabel.Text = 'Ready';
                    return;
                end
            catch
                uiwait(msgbox({'No Atomic Position Checked.' 'Please press the locate button first.'}, 'Error', 'error'));
                app.ApplicationstatusLamp.Color = 'green';
                app.ReadyLabel.Text = 'Ready';
                return;
            end
            
            [ysize,xsize] = size(app.current_image);
            app.gauss_corrected  = false;
            %please give length of pixel on the image for convenience
            
            
            % CofG iterations
            %input from panel
            iterations = 5;
            
            
            h = waitbar(0,'Gaussian 1st-Correction of Atomic Points...');
            app.spotcenters = [];
            app.nofittedpoints = 0;
            for i=1:app.savedpoints
                
                top = app.spotposy(i) - app.radius;
                left = app.spotposx(i) - app.radius;
                bottom = app.spotposy(i) + app.radius;
                right = app.spotposx(i) + app.radius;
                
                if (top<1)
                    top = 1;
                end
                if (left<1)
                    left = 1;
                end
                if (bottom > ysize)
                    bottom = ysize;
                end
                if (right > xsize)
                    right = xsize;
                end
                
                spotimg = app.current_image(top:bottom,left:right);
                
                [centerx,centery] = MeasureImage(app,spotimg,iterations);
                centerx = centerx + left - 1;
                centery = centery + top - 1;
                app.spotcenters = [app.spotcenters; centerx, centery];
                app.nofittedpoints = app.nofittedpoints + 1;
                
                waitbar(i/app.savedpoints);
            end
            close(h);
            
            h = waitbar(0,'Gaussian 2nd-Correction of Atomic Points...');
            app.spotposy = round(app.spotcenters(:,2))';
            app.spotposx = round(app.spotcenters(:,1))';
            app.spotcenters = [];
            app.nofittedpoints = 0;
            for i=1:app.savedpoints
                
                top = app.spotposy(i) - app.radius;
                left = app.spotposx(i) - app.radius;
                bottom = app.spotposy(i) + app.radius;
                right = app.spotposx(i) + app.radius;
                
                if (top<1)
                    top = 1;
                end
                if (left<1)
                    left = 1;
                end
                if (bottom > ysize)
                    bottom = ysize;
                end
                if (right > xsize)
                    right = xsize;
                end
                
                spotimg = app.current_image(top:bottom,left:right);
                
                [centerx,centery] = MeasureImage2(app,spotimg,iterations);
                centerx = centerx + left - 1;
                centery = centery + top - 1;
                app.spotcenters = [app.spotcenters; centerx, centery];
                app.nofittedpoints = app.nofittedpoints + 1;
                
                waitbar(i/app.savedpoints);
            end
            close(h);
            
            
            xgrid = [];
            ygrid = [];
            
            % New algorithm (start)
            %    - Disadvantage: the image should have small position deviations (< app.radius).
            %
            [~,I] = sort(app.spotcenters(:,1));
            trial = app.spotcenters(I,:);
            begin = 1;
            %est_distance = mean(abs(handles.image.spotcenters(1:end-1,1)-handles.image.spotcenters(2:end,1)));
            for i=1:app.nofittedpoints-1
                distance = abs(trial(i,1)-trial(i+1,1));
                
                %if (distance > handles.image.atomicspacing/2)
                if (distance > app.radius*1.3) % more plausible criterion?
                    final = i;
                    xgrid = [xgrid, mean(trial(begin:final,1))];
                    begin = i+1;
                end
            end
            final = app.nofittedpoints;
            xgrid = [xgrid, mean(trial(begin:final,1))];
            
            [~,I] = sort(app.spotcenters(:,2));
            trial = app.spotcenters(I,:);
            begin = 1;
            for i=1:app.nofittedpoints-1
                distance = abs(trial(i,2)-trial(i+1,2));
                
                %if (distance > handles.image.atomicspacing/2)
                if (distance > app.radius*1.3) % more plausible criterion?
                    final = i;
                    ygrid = [ygrid, mean(trial(begin:final,2))];
                    begin = i+1;
                end
            end
            final = app.nofittedpoints;
            ygrid = [ygrid, mean(trial(begin:final,2))];
            
            app.mygrid = cell(0);
            [Y,X] = ndgrid(ygrid,xgrid);
            for i=1:app.nofittedpoints
                distance_matrix = sqrt( (X-app.spotcenters(i,1)).^2 + (Y-app.spotcenters(i,2)).^2 );
                [~,I] = min(distance_matrix(:));
                [row,col] = ind2sub(size(distance_matrix),I);
                app.mygrid{row,col} = app.spotcenters(i,:);
            end
            %
            %    - Disadvantage: the image should have small position deviations (< app.radius).
            % New algorithm (end)
            
            
            h = figure(2002);
            imshow(app.current_image);
            hold on
            for i=1:app.savedpoints
                plot_array_point(app, app.spotcenters(i,2), app.spotcenters(i,1), app.radius,'y',true);
            end
            hold off
            
            uiwait(msgbox('Please check the validity of the Gaussian corrector (yellow)'));
            
            app.gauss_corrected = true;
            
            app.ApplicationstatusLamp.Color = 'green';
            app.ReadyLabel.Text = 'Ready';
        end

        % Button pushed function: CalculateButton
        function CalculateButtonPushed(app, event)
            app.ApplicationstatusLamp.Color = 'red';
            app.ReadyLabel.Text = 'Busy';
            try
                if (~app.gauss_corrected)
                    uiwait(msgbox({'No Gaussian Corrector Checked.' 'Please press the fit button first.'}, 'Error', 'error'));
                    app.ApplicationstatusLamp.Color = 'green';
                    app.ReadyLabel.Text = 'Ready';
                    return;
                end
            catch
                uiwait(msgbox({'No Gaussian Corrector Checked.' 'Please press the fit button first.'}, 'Error', 'error'));
                app.ApplicationstatusLamp.Color = 'green';
                app.ReadyLabel.Text = 'Ready';
                return;
            end
            
            
            choice = questdlg('Does your image appropriately represent atomic sites through the <00c> zone?', ... % Question
                'Confirm', ...  % Title
                'Yes (A-atom)', 'Yes (AB-atom)', 'No', 'No');
            
            switch choice
                case 'No'
                    uiwait(msgbox('Unsupported behavior!'));
                    app.ApplicationstatusLamp.Color = 'green';
                    app.ReadyLabel.Text = 'Ready';
                    return;
            end
            
            
            site_case = -1;
            nospots = floor(app.nofittedpoints);
            
            % CASE 1: detected sites = A-only, perovskite <00c> zone
%             if ( nospots       == floor(sqrt(nospots))^2 ) && ...
%                ( numel(app.mygrid) == nospots )
%                 site_case = 1;
%             end
            if ~( rem(numel(app.mygrid),nospots) )
                site_case = 1;
            end
            
            % CASE 2: detected sites = AB only, perovskite <00c> zone
%             test = floor((nospots-1)/2);
%             for N=1:nospots/2
%                 if (test == N*(N-1) && numel(app.mygrid)>nospots)
%                     site_case = 2;
%                 end
%             end
            if ( numel(app.mygrid) == 2*nospots-1 )
                site_case = 2;
            end
                    
            if (site_case < 0)
                uiwait(msgbox('Please re-check if the image has rectangular array of atomic sites.'));
                app.ApplicationstatusLamp.Color = 'green';
                app.ReadyLabel.Text = 'Ready';
                return;
            end
            
            [ysize,xsize] = size(app.current_image);
            try
            
                if (site_case == 1)
                    
                    [arrayy,arrayx] = size(app.mygrid);
                    if site_case == 1
                        intersect_x = [];
                        intersect_y = [];
                        for j=1:arrayy-1
                            for i=1:arrayx-1
                                x1 = app.mygrid{j,i}(1);
                                y1 = app.mygrid{j,i}(2);
                                
                                x2 = app.mygrid{j,i+1}(1);
                                y2 = app.mygrid{j,i+1}(2);
                                
                                x3 = app.mygrid{j+1,i}(1);
                                y3 = app.mygrid{j+1,i}(2);
                                
                                x4 = app.mygrid{j+1,i+1}(1);
                                y4 = app.mygrid{j+1,i+1}(2);
                                
                                subin_debug = 1;
                                if (subin_debug)
                                    m1 = (y4-y1)/(x4-x1);
                                    m2 = (y2-y3)/(x2-x3);
                                    c1 = y1 - (m1*x1);
                                    c2 = y3 - (m2*x3);
                                    
                                    intersectx = (c2-c1)/(m1-m2);
                                    intersecty = (m1*intersectx)+c1;
                                    
                                    intersect_x(j,i) = intersectx; %round(intersectx);
                                    intersect_y(j,i) = intersecty; %round(intersecty);
                                else
                                    intersect_x(j,i) = (x1+x2+x3+x4)/4;
                                    intersect_y(j,i) = (y1+y2+y3+y4)/4;
                                end
                            end
                        end
                    end


                    % CofG iterations
                    %input from panel
                    iterations = 15;
                    
                    
                    h = waitbar(0,'Gaussian 1st-Correction of Small Atomic Points...');
                    app.est_bsite_x = [];
                    app.est_bsite_y = [];
                    
                    for j=1:arrayy-1
                        for i=1:arrayx-1
                            
                            top    = round(intersect_y(j,i)) - app.radius;%round(markersize*0.8);
                            left   = round(intersect_x(j,i)) - app.radius;%round(markersize*0.8);
                            bottom = round(intersect_y(j,i)) + app.radius;%round(markersize*0.8);
                            right  = round(intersect_x(j,i)) + app.radius;%round(markersize*0.8);
                            
                            if (top<1)
                                top = 1;
                            end
                            if (left<1)
                                left = 1;
                            end
                            if (bottom > ysize)
                                bottom = ysize;
                            end
                            if (right > xsize)
                                right = xsize;
                            end
                            
                            spotimg = app.current_image(top:bottom,left:right);
                            
                            [centerx,centery] = MeasureImage(app,spotimg,iterations);
                            centerx = centerx + left - 1;
                            centery = centery + top - 1;
                            app.est_bsite_x(j,i) = centerx;
                            app.est_bsite_y(j,i) = centery;
                        end
                        waitbar(j/(arrayy-1));
                    end
                    close(h);
                    
                    h = waitbar(0,'Gaussian 2nd-Correction of Small Atomic Points...');
                    prev_pointshiftarrayx = app.est_bsite_x;
                    prev_pointshiftarrayy = app.est_bsite_y;
                    app.est_bsite_x = [];
                    app.est_bsite_y = [];
                    for j=1:arrayy-1
                        for i=1:arrayx-1
                            
                            top    = round(prev_pointshiftarrayy(j,i)) - app.radius;%round(markersize*0.8);
                            left   = round(prev_pointshiftarrayx(j,i)) - app.radius;%round(markersize*0.8);
                            bottom = round(prev_pointshiftarrayy(j,i)) + app.radius;%round(markersize*0.8);
                            right  = round(prev_pointshiftarrayx(j,i)) + app.radius;%round(markersize*0.8);
                            
                            if (top<1)
                                top = 1;
                            end
                            if (left<1)
                                left = 1;
                            end
                            if (bottom > ysize)
                                bottom = ysize;
                            end
                            if (right > xsize)
                                right = xsize;
                            end
                            
                            spotimg = app.current_image(top:bottom,left:right);
                            
                            [centerx,centery] = MeasureImage2(app,spotimg,iterations);
                            centerx = centerx + left - 1;
                            centery = centery + top - 1;
                            app.est_bsite_x(j,i) = centerx;
                            app.est_bsite_y(j,i) = centery;
                        end
                        waitbar(j/(arrayy-1));
                    end
                    close(h);
                    
                elseif (site_case == 2)
                    [arrayy,arrayx] = size(app.mygrid);
                    
                    intersect_x = [];
                    intersect_y = [];
                    for j=1:2:arrayy-1
                        for i=1:2:arrayx-1
                            x1 = app.mygrid{j,i}(1);
                            y1 = app.mygrid{j,i}(2);
                            
                            x2 = app.mygrid{j,i+2}(1);
                            y2 = app.mygrid{j,i+2}(2);
                            
                            x3 = app.mygrid{j+2,i}(1);
                            y3 = app.mygrid{j+2,i}(2);
                            
                            x4 = app.mygrid{j+2,i+2}(1);
                            y4 = app.mygrid{j+2,i+2}(2);
                            
                            subin_debug = 1;
                            if (subin_debug)
                                m1 = (y4-y1)/(x4-x1);
                                m2 = (y2-y3)/(x2-x3);
                                c1 = y1 - (m1*x1);
                                c2 = y3 - (m2*x3);
                                
                                intersectx = (c2-c1)/(m1-m2);
                                intersecty = (m1*intersectx)+c1;
                                
                                intersect_x((j+1)/2,(i+1)/2) = intersectx; %round(intersectx);
                                intersect_y((j+1)/2,(i+1)/2) = intersecty; %round(intersecty);
                            else
                                intersect_x((j+1)/2,(i+1)/2) = (x1+x2+x3+x4)/4;
                                intersect_y((j+1)/2,(i+1)/2) = (y1+y2+y3+y4)/4;
                            end
                        end
                    end
                    
                    app.est_bsite_x = [];
                    app.est_bsite_y = [];
                    
                    for j=2:2:arrayy-1
                        for i=2:2:arrayx-1
                            app.est_bsite_x(j/2,i/2) = app.mygrid{j,i}(1);
                            app.est_bsite_y(j/2,i/2) = app.mygrid{j,i}(2);
                        end
                    end
                    arrayy = (arrayy-1)/2+1;
                    arrayx = (arrayx-1)/2+1;
                end
                
            catch
                uiwait(msgbox('Please re-check if the image has rectangular array of atomic sites.'));
                app.ApplicationstatusLamp.Color = 'green';
                app.ReadyLabel.Text = 'Ready';
                return;
            end
                
            xcalc = intersect_x;
            ycalc = intersect_y;
            xmeas = app.est_bsite_x;
            ymeas = app.est_bsite_y;
            
            % Conventional y direction
            ycalc = ysize - ycalc;
            ymeas = ysize - ymeas;
            
            % Autoscale
            value = app.Switch_2.Value;
            switch value
                case 'Auto scal. ON'
                    % vectors pointing from cal(centralpoint) to meas(pointshift; real points)
                    xdistance = app.scale(1) * (xmeas - xcalc);
                    ydistance = app.scale(2) * (ymeas - ycalc);
                otherwise
                    xdistance = (xmeas - xcalc);
                    ydistance = (ymeas - ycalc);
            end
            
            veclength = sqrt( (xdistance).^2 + (ydistance).^2 );
            app.angle  = atan(abs(ydistance./xdistance)); % angle in radian
            app.angle  = 180*app.angle/pi; % angle in degree
            
            %quad1 = (ydistance >= 0) & (xdistance >= 0);
            quad2 = (ydistance >  0) & (xdistance <  0);
            quad3 = (ydistance <= 0) & (xdistance <= 0);
            quad4 = (ydistance <  0) & (xdistance >  0);
            
            app.angle(quad2) = 180 - app.angle(quad2);
            app.angle(quad3) = 180 + app.angle(quad3);
            app.angle(quad4) = 360 - app.angle(quad4);
            
            app.length  = veclength;
            app.xvector = xdistance;
            app.yvector = ydistance;
            
            h = figure(260);
            imshow(app.current_image);
            hold on
            for i=1:app.savedpoints
                plot_array_point(app,app.spotcenters(i,2), app.spotcenters(i,1), app.radius,'y',true);
            end
            for j=1:arrayy-1
                for i=1:arrayx-1
                    plot_array_point(app,intersect_y(j,i),intersect_x(j,i), app.radius,'y',true);
                    plot_array_point(app,app.est_bsite_y(j,i),app.est_bsite_x(j,i), app.radius,'m',true);

                    x = [intersect_x(j,i), app.est_bsite_x(j,i)];
                    y = [intersect_y(j,i), app.est_bsite_y(j,i)];
                    %drawArrow(x,y,'AutoScale','off','MaxHeadSize',0.9,'linewidth',2,'color','y');
                    %drawArrow(x,y,{'Linewidth',3,'Color','y'});
                end
            end
            
            q = quiver(intersect_x,intersect_y, ...
                (app.est_bsite_x - intersect_x), ...
                (app.est_bsite_y - intersect_y));
            %     q.AutoScale = 'off';
            q.Color = 'y';
            q.LineStyle = '-';
            %     q.MaxHeadSize = 10;
            q.LineWidth = 2.0;


            hold off
            %handles.image.calculated_image = h;
            
            uiwait(msgbox('Please check the validity of the Gaussian corrector (centered yellow)'));
            
            app.ApplicationstatusLamp.Color = 'green';
            app.ReadyLabel.Text = 'Ready';
        end

        % Value changed function: CorrFactSlider
        function CorrFactSliderValueChanged(app, event)
            value = app.CorrFactSlider.Value;
            app.Label.Text = num2str(value);
        end

        % Button pushed function: SaveDataButton
        function SaveDataButtonPushed(app, event)
            app.ApplicationstatusLamp.Color = 'red';
            app.ReadyLabel.Text = 'Busy';
            try
                if (~app.gauss_corrected)
                    uiwait(msgbox({'No Gaussian Corrector Checked.' 'Please press the fit button first.'}, 'Error', 'error'));
                    app.ApplicationstatusLamp.Color = 'green';
                    app.ReadyLabel.Text = 'Ready';
                    return;
                end
            catch
                uiwait(msgbox({'No Gaussian Corrector Checked.' 'Please press the fit button first.'}, 'Error', 'error'));
                app.ApplicationstatusLamp.Color = 'green';
                app.ReadyLabel.Text = 'Ready';
                return;
            end
            
            try
                error_test = app.est_bsite_x(1);
                [ysize  ,xsize  ] = size(app.mygrid);
                [ysize_B,xsize_B] = size(app.est_bsite_x);
                app.est_asite_x = [];
                app.est_asite_y = [];
                
                if (ysize > ysize_B+1 || xsize > xsize_B+1)
                    for j=1:2:ysize
                        for i=1:2:xsize
                            app.est_asite_x((j+1)/2,(i+1)/2) = app.mygrid{j,i}(1);
                            app.est_asite_y((j+1)/2,(i+1)/2) = app.mygrid{j,i}(2);
                        end
                    end
                elseif (ysize == ysize_B+1 && xsize == xsize_B+1)
                    for j=1:ysize
                        for i=1:xsize
                            app.est_asite_x(j,i) = app.mygrid{j,i}(1);
                            app.est_asite_y(j,i) = app.mygrid{j,i}(2);
                        end
                    end
                else
                    uiwait(msgbox('Unknown Error: please call the author of this code.'));
                    app.ApplicationstatusLamp.Color = 'green';
                    app.ReadyLabel.Text = 'Ready';
                    return;
                end
                
                % Conventional y direction
                [ysize,~] = size(app.current_image);
                my_est_asite_y = ysize - app.est_asite_y;
                my_est_bsite_y = ysize - app.est_bsite_y;
                my_est_asite_x = app.est_asite_x;
                my_est_bsite_x = app.est_bsite_x;
                
                % Autoscale
                value = app.Switch_2.Value;
                switch value
                    case 'Auto scal. ON'
                        my_est_asite_x = my_est_asite_x * app.scale(1);
                        my_est_bsite_x = my_est_bsite_x * app.scale(1);
                        my_est_asite_y = my_est_asite_y * app.scale(2);
                        my_est_bsite_y = my_est_bsite_y * app.scale(2);
                end
                
                my_image.angle   = app.angle;
                my_image.length  = app.length;
                my_image.xvector = app.xvector;
                my_image.yvector = app.yvector;
                my_image.scale   = app.scale;
                my_image.units   = app.units;
                my_image.fitted_A_sites_x = my_est_asite_x;
                my_image.fitted_A_sites_y = my_est_asite_y;
                my_image.fitted_B_sites_x = my_est_bsite_x;
                my_image.fitted_B_sites_y = my_est_bsite_y;
            catch

                my_atomic_point_x = [];
                my_atomic_point_y = [];
                [ysize,xsize] = size(app.mygrid);
                
                count = 1;
                for j=1:ysize
                    for i=1:xsize
                        if numel(app.mygrid{j,i})
                            my_atomic_point_x(count) = app.mygrid{j,i}(1);
                            my_atomic_point_y(count) = app.mygrid{j,i}(2);
                            count = count + 1;
                        end
                    end
                end
                
                % Conventional y direction
                [ysize,~] = size(app.current_image);
                my_atomic_point_y = ysize - my_atomic_point_y;
                
                % Autoscale
                value = app.Switch_2.Value;
                switch value
                    case 'Auto scal. ON'
                        my_atomic_point_x = my_atomic_point_x * app.scale(1);
                        my_atomic_point_y = my_atomic_point_y * app.scale(2);
                end
                my_image.fitted_atom_sites_x = my_atomic_point_x;
                my_image.fitted_atom_sites_y = my_atomic_point_y;
            end
            
            try             
                my_message = {'Variable name you want to specify: '};
                title = 'Save Data';
                lines = 1;
                default = {'my_image'};
                answer = inputdlg(my_message, title, lines, default);
                assignin('base', answer{1}, my_image);
                
                if(numel(answer)<1)
                    uiwait(msgbox('Failed to save your data!'));
                    app.ApplicationstatusLamp.Color = 'green';
                    app.ReadyLabel.Text = 'Ready';
                    return;
                else
                    try
                        folder_name = uigetdir('C:\Users\','Select Folder to Store Results');
                        
                        A_fileID = fopen([folder_name '\' answer{1} '_site_A.txt'],'w');
                        B_fileID = fopen([folder_name '\' answer{1} '_site_B.txt'],'w'); 
                        if (A_fileID<3 || B_fileID<3)
                            uiwait(msgbox('Failed to save a file!'));
                            app.ApplicationstatusLamp.Color = 'green';
                            app.ReadyLabel.Text = 'Ready';
                            return;
                        end
                        [~,col] = size(my_image.fitted_A_sites_x);
                        fitted_A_sites(:,1:2:2*col-1) = my_image.fitted_A_sites_x(:,:);
                        fitted_A_sites(:,2:2:2*col  ) = my_image.fitted_A_sites_y(:,:);
                        formatSpec_A = '';
                        for i=1:col
                            formatSpec_A = [formatSpec_A '%8.3f  %8.3f \t'];
                        end
                        formatSpec_A = [formatSpec_A '\r\n'];
                        
                        [~,col] = size(my_image.fitted_B_sites_x);
                        fitted_B_sites(:,1:2:2*col-1) = my_image.fitted_B_sites_x(:,:);
                        fitted_B_sites(:,2:2:2*col  ) = my_image.fitted_B_sites_y(:,:);
                        formatSpec_B = '';
                        for i=1:col
                            formatSpec_B = [formatSpec_B '%8.3f  %8.3f \t'];
                        end
                        formatSpec_B = [formatSpec_B '\r\n'];
                        
                        % fprintf reads a matrix with a column-major order
                        fprintf(A_fileID,formatSpec_A,fitted_A_sites');
                        fprintf(B_fileID,formatSpec_B,fitted_B_sites');
                        imwrite(app.current_image,[folder_name '\' answer{1} '.png']);
                        
                        fclose(A_fileID);
                        fclose(B_fileID);
                    catch
                        uiwait(msgbox('No Calculate button: exporting data to external files is not supported.'));
                        app.ApplicationstatusLamp.Color = 'green';
                        app.ReadyLabel.Text = 'Ready';
                        return;
                    end
                end
            catch
                uiwait(msgbox('Inappropriate variable name!'));
                app.ApplicationstatusLamp.Color = 'green';
                app.ReadyLabel.Text = 'Ready';
                return;
            end
            
            app.ApplicationstatusLamp.Color = 'green';
            app.ReadyLabel.Text = 'Ready';
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.PangMicrographUIFigure = uifigure;
            app.PangMicrographUIFigure.Name = 'Pang Micrograph';
            app.TabGroup = uitabgroup(app.PangMicrographUIFigure);
            app.ParticleDetectionTab = uitab(app.TabGroup);
            app.ParticleDetectionTab.Title = 'Particle Detection';
            app.ImportImageButton = uibutton(app.ParticleDetectionTab, 'push');
            app.ImportImageButton.ButtonPushedFcn = createCallbackFcn(app, @ImportImageButtonPushed, true);
            app.ImportImageButton.Text = 'Import Image';
            app.YourImageLabel = uilabel(app.ParticleDetectionTab);
            app.YourImageLabel.Text = 'Your Image';
            app.ApplicationStatusLabel = uilabel(app.ParticleDetectionTab);
            app.ApplicationStatusLabel.Text = 'Application Status';
            app.ApplicationstatusLamp = uilamp(app.ParticleDetectionTab);
            app.ReadyLabel = uilabel(app.ParticleDetectionTab);
            app.ReadyLabel.Text = 'Ready';
            app.AnalysisToolLabel = uilabel(app.ParticleDetectionTab);
            app.AnalysisToolLabel.Text = 'Analysis Tool';
            app.RotateButton = uibutton(app.ParticleDetectionTab, 'push');
            app.RotateButton.ButtonPushedFcn = createCallbackFcn(app, @RotateButtonPushed, true);
            app.RotateButton.Text = 'Rotate';
            app.SpecifyButton = uibutton(app.ParticleDetectionTab, 'push');
            app.SpecifyButton.ButtonPushedFcn = createCallbackFcn(app, @SpecifyButtonPushed, true);
            app.SpecifyButton.Text = 'Specify';
            app.ModifyButton = uibutton(app.ParticleDetectionTab, 'push');
            app.ModifyButton.ButtonPushedFcn = createCallbackFcn(app, @ModifyButtonPushed, true);
            app.ModifyButton.Text = 'Modify';
            app.FitButton = uibutton(app.ParticleDetectionTab, 'push');
            app.FitButton.ButtonPushedFcn = createCallbackFcn(app, @FitButtonPushed, true);
            app.FitButton.Text = 'Fit';
            app.CalculateButton = uibutton(app.ParticleDetectionTab, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CalculateButton.Text = 'Calculate';
            app.CorrFactSliderLabel = uilabel(app.ParticleDetectionTab);
            app.CorrFactSliderLabel.Text = 'Corr. Fact.';
            app.CorrFactSlider = uislider(app.ParticleDetectionTab);
            app.CorrFactSlider.Limits = [0 1];
            app.CorrFactSlider.MajorTicks = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.CorrFactSlider.ValueChangedFcn = createCallbackFcn(app, @CorrFactSliderValueChanged, true);
            app.CorrFactSlider.Value = 0.8;
            app.UITable = uitable(app.ParticleDetectionTab);
            app.UITable.ColumnName = {'Data Name'; 'Data Value'; 'Message'};
            app.UITable.RowName = {};
            app.SaveDataButton = uibutton(app.ParticleDetectionTab, 'push');
            app.SaveDataButton.ButtonPushedFcn = createCallbackFcn(app, @SaveDataButtonPushed, true);
            app.SaveDataButton.Text = 'Save Data';
            app.Label = uilabel(app.ParticleDetectionTab);
            app.Label.Text = '0.8';
            app.ReadyLabel_2 = uilabel(app.ParticleDetectionTab);
            app.ReadyLabel_2.Text = {'Though the status is ''ready'', it may take few seconds for plot-functions'; 'to show high-resolution images.'};
            app.Switch_2 = uiswitch(app.ParticleDetectionTab, 'slider');
            app.Switch_2.Items = {'Auto scal. OFF', 'Auto scal. ON'};
            app.Switch_2.Value = 'Auto scal. OFF';
            app.LocateButton = uibutton(app.ParticleDetectionTab, 'push');
            app.LocateButton.ButtonPushedFcn = createCallbackFcn(app, @LocateButtonPushed, true);
            app.LocateButton.Text = 'Locate';
            app.UIAxes = uiaxes(app.ParticleDetectionTab);
            app.SmoothingToolkitTab = uitab(app.TabGroup);
            app.SmoothingToolkitTab.Title = 'Smoothing Toolkit';
            app.YourImageLabel_2 = uilabel(app.SmoothingToolkitTab);
            app.YourImageLabel_2.Text = 'Your Image';
            app.UIAxes2 = uiaxes(app.SmoothingToolkitTab);
            app.ImportDataButton = uibutton(app.SmoothingToolkitTab, 'push');
            app.ImportDataButton.Text = 'Import Data';
            app.ImportDataButton_2 = uibutton(app.SmoothingToolkitTab, 'push');
            app.ImportDataButton_2.Text = 'Import Data';
            app.SpectralAnalysisTab = uitab(app.TabGroup);
            app.SpectralAnalysisTab.Title = 'Spectral Analysis';
            app.DiffractionIndexingTab = uitab(app.TabGroup);
            app.DiffractionIndexingTab.Title = 'Diffraction Indexing';
            app.GeometricPhaseAnalysisTab = uitab(app.TabGroup);
            app.GeometricPhaseAnalysisTab.Title = 'Geometric Phase Analysis';
            app.DiagonalizationToolkitTab = uitab(app.TabGroup);
            app.DiagonalizationToolkitTab.Title = 'Diagonalization Toolkit';
        end
    end

    methods (Access = public)

        % Construct app
        function app = dm

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.PangMicrographUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.PangMicrographUIFigure)
        end
    end
end